classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Button_4                    matlab.ui.control.ToggleButton
        SegmentDataButton           matlab.ui.control.ToggleButton
        ExportDataButton            matlab.ui.control.ToggleButton
        Button3                     matlab.ui.control.ToggleButton
        ApplyFiltersButton          matlab.ui.control.ToggleButton
        LoadDataButton              matlab.ui.control.ToggleButton
        ColorPicker                 matlab.ui.control.ColorPicker
        ColorPickerLabel            matlab.ui.control.Label
        NumericalTabGroup           matlab.ui.container.TabGroup
        NumericalResultsTab         matlab.ui.container.Tab
        Panel3                      matlab.ui.container.Panel
        Panel2                      matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        DataFormatButtonGroup       matlab.ui.container.ButtonGroup
        NumericalButton             matlab.ui.control.RadioButton
        GraphsButton                matlab.ui.control.RadioButton
        GraphTabGroup               matlab.ui.container.TabGroup
        RawEMGSignalPlotTab         matlab.ui.container.Tab
        UIAxes_4                    matlab.ui.control.UIAxes
        FilteredEMGSignalPlotTab    matlab.ui.container.Tab
        UIAxes_3                    matlab.ui.control.UIAxes
        SegmentedGaitCyclesTab      matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        EMGvsGaitEventsTab          matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        SegmentationSlider          matlab.ui.control.Slider
        SegmentationSliderLabel     matlab.ui.control.Label
        CutOffFrequencySlider       matlab.ui.control.Slider
        CutOffFrequencySliderLabel  matlab.ui.control.Label
        FilterTypeDropDown          matlab.ui.control.DropDown
        FilterTypeDropDownLabel     matlab.ui.control.Label
        MusclesDropDown             matlab.ui.control.DropDown
        MusclesDropDownLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: DataFormatButtonGroup
        function DataFormatButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataFormatButtonGroup.SelectedObject;
if selectedButton == app.GraphsButton
    app.GraphTabGroup.Visible = 'on';
    app.NumericalTabGroup.Visible = 'off';
else % Numerical button is selected
    app.GraphTabGroup.Visible = 'off';
    app.NumericalTabGroup.Visible = 'on';
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MusclesDropDownLabel = uilabel(app.UIFigure);
            app.MusclesDropDownLabel.Text = 'Muscles ';
            app.MusclesDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.CutOffFrequencySliderLabel = uilabel(app.UIFigure);
            app.CutOffFrequencySliderLabel.Text = 'Cut--Off Frequency';
            app.CutOffFrequencySlider = uislider(app.UIFigure);
            app.SegmentationSliderLabel = uilabel(app.UIFigure);
            app.SegmentationSliderLabel.Text = 'Segmentation';
            app.SegmentationSlider = uislider(app.UIFigure);
            app.GraphTabGroup = uitabgroup(app.UIFigure);
            app.RawEMGSignalPlotTab = uitab(app.GraphTabGroup);
            app.RawEMGSignalPlotTab.Title = 'Raw EMG Signal Plot ';
            app.UIAxes_4 = uiaxes(app.RawEMGSignalPlotTab);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.FilteredEMGSignalPlotTab = uitab(app.GraphTabGroup);
            app.FilteredEMGSignalPlotTab.Title = 'Filtered EMG Signal Plot';
            app.UIAxes_3 = uiaxes(app.FilteredEMGSignalPlotTab);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SegmentedGaitCyclesTab = uitab(app.GraphTabGroup);
            app.SegmentedGaitCyclesTab.Title = 'Segmented Gait Cycles';
            app.UIAxes_2 = uiaxes(app.SegmentedGaitCyclesTab);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.EMGvsGaitEventsTab = uitab(app.GraphTabGroup);
            app.EMGvsGaitEventsTab.Title = 'EMG vs. Gait Events';
            app.UIAxes = uiaxes(app.EMGvsGaitEventsTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataFormatButtonGroup = uibuttongroup(app.UIFigure);
            app.DataFormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataFormatButtonGroupSelectionChanged, true);
            app.DataFormatButtonGroup.Title = 'Data Format';
            app.GraphsButton = uiradiobutton(app.DataFormatButtonGroup);
            app.GraphsButton.Text = 'Graphs';
            app.GraphsButton.Value = true;
            app.NumericalButton = uiradiobutton(app.DataFormatButtonGroup);
            app.NumericalButton.Text = 'Numerical';
            app.NumericalTabGroup = uitabgroup(app.UIFigure);
            app.NumericalTabGroup.Visible = 'off';
            app.NumericalResultsTab = uitab(app.NumericalTabGroup);
            app.NumericalResultsTab.Title = 'Numerical Results';
            app.Panel = uipanel(app.NumericalResultsTab);
            app.Panel.Title = 'Panel';
            app.Panel2 = uipanel(app.NumericalResultsTab);
            app.Panel2.Title = 'Panel2';
            app.Panel3 = uipanel(app.NumericalResultsTab);
            app.Panel3.Title = 'Panel3';
            app.ColorPickerLabel = uilabel(app.UIFigure);
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ColorPicker = uicolorpicker(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.LoadDataButton = uitogglebutton(app.ButtonGroup);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadDataButton.Value = true;
            app.ApplyFiltersButton = uitogglebutton(app.ButtonGroup);
            app.ApplyFiltersButton.Text = 'Apply Filters';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.ExportDataButton = uitogglebutton(app.ButtonGroup);
            app.ExportDataButton.Text = 'Export Data';
            app.SegmentDataButton = uitogglebutton(app.ButtonGroup);
            app.SegmentDataButton.Text = 'Segment Data';
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = 'Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end